classdef MagneticControlSystemGUI3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        XSlider                         matlab.ui.control.Slider
        XFieldLabel                     matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        YFieldLabel                     matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
        ZFieldLabel                     matlab.ui.control.Label
        DMagneticControlSystemLabel     matlab.ui.control.Label
        XFieldDirectionSwitchLabel      matlab.ui.control.Label
        XFieldDirectionSwitch           matlab.ui.control.Switch
        ZFieldDirectionSwitchLabel      matlab.ui.control.Label
        ZFieldDirectionSwitch           matlab.ui.control.Switch
        PowerOffButton                  matlab.ui.control.Button
        UpdateSimulationButton          matlab.ui.control.Button
        SendSignaltoSystemButton        matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        PressthisbuttontocutpowertoallmotordriversLabel  matlab.ui.control.Label
        YFieldDirectionSwitchLabel      matlab.ui.control.Label
        YFieldDirectionSwitch           matlab.ui.control.Switch
        XLamp                           matlab.ui.control.Lamp
        YLamp                           matlab.ui.control.Lamp
        ZLamp                           matlab.ui.control.Lamp
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        XAxesSeparationmmDropDownLabel  matlab.ui.control.Label
        XAxesSeparationmmDropDown       matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        YAxesSeparationmmDropDownLabel  matlab.ui.control.Label
        YAxesSeparationmmDropDown       matlab.ui.control.DropDown
        MagneticFieldDensityVectorMagnitudeLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a 
        Baudrate=4800

    end
    
    methods (Access = private)
        
        function plotme(app)
            axis(app.UIAxes,[-10,10,-10,10,-15,15])
            XField=app.XSlider.Value; 
            switch app.XFieldDirectionSwitch.Value
                case 'Positive'
                    XField=XField;
                case 'Negative'
                    XField=XField*-1;
            end
            YField=app.YSlider.Value;
            switch app.YFieldDirectionSwitch.Value
                case 'Positive'
                    YField=YField;
                case 'Negative'
                    YField=YField*-1;
            end
            ZField=app.ZSlider.Value;
            switch app.ZFieldDirectionSwitch.Value
                case 'Positive'
                    ZField=ZField;
                case 'Negative'
                    ZField=ZField*-1;
            end
            Start=[0,0,0];
            % For final app, perform magnetic field density calculation
            % here. Include an option in the GUI to vary the solenoid
            % distance
            
            mu0=4*pi*10^-7;% permeability of air %need to confirm this value
            R=0.037; %Radius of solenoids
            nTurns=110; %Number of coil turns
            separationDist = 0.1328; %Separation Distance between each pair of solenoids
            z_axial = 0; % to compute the magnetic field density at the test chamber
            separationDistX=app.XAxesSeparationmmDropDown.Value;
            switch app.XAxesSeparationmmDropDown.Value
                case '124'
                    separationDistX=124;
                case '161'
                    separationDistX=161;
                case '201'
                    separationDistX=201;
                case '239'
                    separationDistX=239;
                case '277'
                    separationDistX=277;
                case '312'
                    separationDistX=312;
            end
            separationDistX=separationDistX/1000;
            separationDistY=app.YAxesSeparationmmDropDown.Value;
            switch app.YAxesSeparationmmDropDown.Value
                case '124'
                    separationDistY=124;
                case '161'
                    separationDistY=161;
                case '201'
                    separationDistY=201;
                case '239'
                    separationDistY=239;
                case '277'
                    separationDistY=277;
                case '312'
                    separationDistY=312;
            end
            separationDistY=separationDistY/1000;
            separationDistZ=0.070866; %top bottom solenoid separation distance in mm; 2.79 inches
            %Computes field in Teslas
            BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
            BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
            BZ=0.5*mu0*nTurns*ZField*(R^2)*((R^2+(z_axial+(separationDistZ/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistZ/2).^2).^(-3/2));
            %Converts to milliteslas
            BX=BX*1000;
            BY=BY*1000;
            BZ=BZ*1000;
            pts=[Start;BX,BY,BZ];

            app.XFieldLabel.Text = char("X Direction Magnetic Field Density: " + string(BX) + ' Milliteslas at '+string(XField)+' Amps');
            app.YFieldLabel.Text = char("Y Direction Magnetic Field Density: " + string(BY) + ' Milliteslas at '+string(YField)+' Amps');
            app.ZFieldLabel.Text = char("Z Direction Magnetic Field Density: " + string(BZ) + ' Milliteslas at '+string(ZField)+' Amps');
            %pts=[Start;XField,YField,ZField];
            cla(app.UIAxes)
            plot3(app.UIAxes,0,0,0,'o','linewidth',4,'Color','k');
            hold(app.UIAxes,'on')
            plot3(app.UIAxes,pts(:,1),pts(:,2),pts(:,3),'Color','r','linewidth',4)
            view(app.UIAxes,[-5 -5 2]) 
            grid(app.UIAxes, 'on')
            legend(app.UIAxes,'Test Chamber','Simulated Magnetic Field Vector','location',"South")
            
            %axis(app.UIAxes,[0,10,0,10,0,10])
            xlabel(app.UIAxes,'X-Axes Field Density [mT]'); %Update to Teslas 
            ylabel(app.UIAxes,'Y-Axes Field Density [mT]');
            zlabel(app.UIAxes,'Z-Axes Field Density [mT]');
%             xlabel(app.UIAxes,'X-Axes [Amps]'); %Update to Teslas 
%             ylabel(app.UIAxes,'Y-Axes [Amps]');
%             zlabel(app.UIAxes,'Z-Axes [Amps]');
            title(app.UIAxes,'Magnetic Field Density Vector')
            Magnitude=sqrt(BX^2+BY^2+BZ^2);
            app.MagneticFieldDensityVectorMagnitudeLabel.Text = char("Magnetic Field Density Vector Magnitude: " + string(Magnitude) + ' Milliteslas');
        end
        
        function sliderset(app)
            app.XFieldLabel.Text = char("X Direction Magnetic Field Density Current [Amps]");
            app.YFieldLabel.Text = char("Y Direction Magnetic Field Density Current [Amps]");
            app.ZFieldLabel.Text = char("Z Direction Magnetic Field Density Current [Amps]");
        end
        
        function plotme2(app)
            axis(app.UIAxes,[-10,10,-10,10,-15,15])
            XField=app.XSlider.Value; 
            switch app.XFieldDirectionSwitch.Value
                case 'Positive'
                    XField=XField;
                case 'Negative'
                    XField=XField*-1;
            end
            YField=app.YSlider.Value;
            switch app.YFieldDirectionSwitch.Value
                case 'Positive'
                    YField=YField;
                case 'Negative'
                    YField=YField*-1;
            end
            ZField=app.ZSlider.Value;
            switch app.ZFieldDirectionSwitch.Value
                case 'Positive'
                    ZField=ZField;
                case 'Negative'
                    ZField=ZField*-1;
            end
            Start=[0,0,0];
            % For final app, perform magnetic field density calculation
            % here. Include an option in the GUI to vary the solenoid
            % distance
            
            mu0=4*pi*10^-7;% permeability of air %need to confirm this value
            R=0.037; %Radius of solenoids
            nTurns=110; %Number of coil turns
            separationDist = 0.1328; %Separation Distance between each pair of solenoids
            z_axial = 0; % to compute the magnetic field density at the test chamber
            separationDistX=app.XAxesSeparationmmDropDown.Value;
            switch app.XAxesSeparationmmDropDown.Value
                case '124'
                    separationDistX=124;
                case '161'
                    separationDistX=161;
                case '201'
                    separationDistX=201;
                case '239'
                    separationDistX=239;
                case '277'
                    separationDistX=277;
                case '312'
                    separationDistX=312;
            end
            separationDistX=separationDistX/1000;
            separationDistY=app.YAxesSeparationmmDropDown.Value;
            switch app.YAxesSeparationmmDropDown.Value
                case '124'
                    separationDistY=124;
                case '161'
                    separationDistY=161;
                case '201'
                    separationDistY=201;
                case '239'
                    separationDistY=239;
                case '277'
                    separationDistY=277;
                case '312'
                    separationDistY=312;
            end
            separationDistY=separationDistY/1000;
            separationDistZ=0.070866; %top bottom solenoid separation distance in mm; 2.79 inches
            %Computes field in Teslas
            BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
            BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
            BZ=0.5*mu0*nTurns*ZField*(R^2)*((R^2+(z_axial+(separationDistZ/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistZ/2).^2).^(-3/2));
            %Converts to milliteslas
            BX=BX*1000;
            BY=BY*1000;
            BZ=BZ*1000;
            pts=[Start;BX,BY,BZ];

            app.XFieldLabel.Text = char("X Direction Magnetic Field Density: " + string(BX) + ' Milliteslas at '+string(XField)+' Amps');
            app.YFieldLabel.Text = char("Y Direction Magnetic Field Density: " + string(BY) + ' Milliteslas at '+string(YField)+' Amps');
            app.ZFieldLabel.Text = char("Z Direction Magnetic Field Density: " + string(BZ) + ' Milliteslas at '+string(ZField)+' Amps');
            %pts=[Start;XField,YField,ZField];
            cla(app.UIAxes)
            plot3(app.UIAxes,0,0,0,'o','linewidth',4,'Color','k');
            hold(app.UIAxes,'on')
            plot3(app.UIAxes,pts(:,1),pts(:,2),pts(:,3),'Color','r','linewidth',4)
            view(app.UIAxes,[-5 -5 2]) 
            grid(app.UIAxes, 'on')
            legend(app.UIAxes,'Test Chamber','Simulated Magnetic Field Vector','location',"South")
            
            %axis(app.UIAxes,[0,10,0,10,0,10])
            xlabel(app.UIAxes,'X-Axes Field Density [mT]'); %Update to Teslas 
            ylabel(app.UIAxes,'Y-Axes Field Density [mT]');
            zlabel(app.UIAxes,'Z-Axes Field Density [mT]');
%             xlabel(app.UIAxes,'X-Axes [Amps]'); %Update to Teslas 
%             ylabel(app.UIAxes,'Y-Axes [Amps]');
%             zlabel(app.UIAxes,'Z-Axes [Amps]');
            title(app.UIAxes,'Magnetic Field Density Vector')
            Magnitude=sqrt(BX^2+BY^2+BZ^2);
            app.MagneticFieldDensityVectorMagnitudeLabel.Text = char("Magnetic Field Density Vector Magnitude: " + string(Magnitude) + ' Milliteslas');
            %feedback stuff
%             pause(5)
%             xammeter=readVoltage(app.a,'A8');
%             yammeter=readVoltage(app.a,'A9');
%             zammeter=readVoltage(app.a,'A10');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.a=arduino();
            app.plotme();
            app.sliderset();
            app.XLamp.Enable = 'off';
            app.YLamp.Enable = 'off';
            app.ZLamp.Enable = 'off';
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValueX = event.Value;
            %XField=event.Value;
            app.XFieldLabel.Text = char("X Direction Applied Current: " + string(changingValueX) + ' Amps');
            %writePWMDutyCycle(app.a, 'D4', changingValueX/10.0);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValueY = event.Value;
            app.YFieldLabel.Text = char("Y Direction Magnetic Field Density: " + string(changingValueY) + ' Amps');
            %writePWMDutyCycle(app.a, 'D5', changingValueY/10.0);            
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValueZ = event.Value;
            app.ZFieldLabel.Text = char("Y Direction Magnetic Field Density: " + string(changingValueZ) + ' Amps');
            %writePWMDutyCycle(app.a, 'D6', changingValueZ/10.0); 
        end

        % Value changed function: ZFieldDirectionSwitch
        function ZFieldDirectionSwitchValueChanged(app, event)
            value = app.ZFieldDirectionSwitch.Value;
%             switch value
%                 case 'Positive'
%                     writePWMDutyCycle(app.a, 'D7', 1);
%                 case 'Negative'
%                     writePWMDutyCycle(app.a, 'D7', 0);
%             end
        end

        % Callback function
        function YFieldDirectionSwitchValueChanged(app, event)
            
        end

        % Value changed function: XFieldDirectionSwitch
        function XFieldDirectionSwitchValueChanged(app, event)
            value = app.XFieldDirectionSwitch.Value;
%             switch value
%                 case 'Positive'
%                     %writePWMDutyCycle(app.a, 'D2', 1);
%                 case 'Negative'
%                     %writePWMDutyCycle(app.a, 'D2', 0);
%             end
        end

        % Button pushed function: SendSignaltoSystemButton
        function SendSignaltoSystemButtonPushed(app, event)
            writePWMDutyCycle(app.a, 'D3', app.XSlider.Value/10.0);
            if app.XSlider.Value>0
                app.XLamp.Enable = 'on';
            else
                app.XLamp.Enable = 'off';
            end
            switch app.XFieldDirectionSwitch.Value
                case 'Positive'
                    %writePWMDutyCycle(app.a, 'D2', 1);
                case 'Negative'
                    %writePWMDutyCycle(app.a, 'D2', 0);
            end
            writePWMDutyCycle(app.a, 'D5', app.YSlider.Value/10.0);
            if app.YSlider.Value>0
                app.YLamp.Enable = 'on';
            else
                app.YLamp.Enable = 'off';
            end
            switch app.YFieldDirectionSwitch.Value
                case 'Positive'
                    %writePWMDutyCycle(app.a, 'D4', 1);
                case 'Negative'
                    %writePWMDutyCycle(app.a, 'D4', 0);
            end
            writePWMDutyCycle(app.a, 'D6', app.ZSlider.Value/10.0);
            if app.ZSlider.Value>0
                app.ZLamp.Enable = 'on';
            else
                app.ZLamp.Enable = 'off';
            end
            switch app.ZFieldDirectionSwitch.Value
                case 'Positive'
                    writePWMDutyCycle(app.a, 'D7', 1);
                case 'Negative'
                    writePWMDutyCycle(app.a, 'D7', 0);
            end
            
%             pause(5)
%             xammeter=readVoltage(app.a,'A8');
%             yammeter=readVoltage(app.a,'A9');
%             Zammeter=readVoltage(app.a,'A10');
            
%             if (xammeter>10)||(yammeter>10)||(zammeter>10)
%                 if xammeter>10
%                     writePWMDutyCycle(app.a, 'D3', app.XSlider.Value/10.0)
%                 end
%             end
            
            app.plotme()
            %app.plotme2()
        end

        % Button pushed function: UpdateSimulationButton
        function UpdateSimulationButtonPushed(app, event)
            app.plotme()
        end

        % Button pushed function: PowerOffButton
        function PowerOffButtonPushed(app, event)
            writePWMDutyCycle(app.a, 'D3', 0);
            writePWMDutyCycle(app.a, 'D5', 0);
            writePWMDutyCycle(app.a, 'D6', 0);
            app.XLamp.Enable = 'off';
            app.YLamp.Enable = 'off';
            app.ZLamp.Enable = 'off';
%             try
%                 app.XSlider.Value = str2double(5);
%                 app.YSlider.Value = str2double(0);
%                 app.ZSlider.Value = str2double(0);
%             catch ME
%                 
%             end
        end

        % Value changed function: YFieldDirectionSwitch
        function YFieldDirectionSwitchValueChanged2(app, event)
            value = app.YFieldDirectionSwitch.Value;
            
        end

        % Value changed function: XAxesSeparationmmDropDown
        function XAxesSeparationmmDropDownValueChanged(app, event)
            XSeparationDistancemm = app.XAxesSeparationmmDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 10];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XFieldLabel = uilabel(app.UIFigure);
            app.XFieldLabel.Text = 'X-Field';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 10];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YFieldLabel = uilabel(app.UIFigure);
            app.YFieldLabel.Text = 'Y-Field';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [0 10];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZFieldLabel = uilabel(app.UIFigure);
            app.ZFieldLabel.Text = 'Z-Field';
            app.DMagneticControlSystemLabel = uilabel(app.UIFigure);
            app.DMagneticControlSystemLabel.Text = '3D Magnetic Control System ';
            app.XFieldDirectionSwitchLabel = uilabel(app.UIFigure);
            app.XFieldDirectionSwitchLabel.Text = 'X-Field Direction';
            app.XFieldDirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.XFieldDirectionSwitch.Items = {'Negative', 'Positive'};
            app.XFieldDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @XFieldDirectionSwitchValueChanged, true);
            app.XFieldDirectionSwitch.Value = 'Positive';
            app.ZFieldDirectionSwitchLabel = uilabel(app.UIFigure);
            app.ZFieldDirectionSwitchLabel.Text = 'Z-Field Direction';
            app.ZFieldDirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.ZFieldDirectionSwitch.Items = {'Negative', 'Positive'};
            app.ZFieldDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ZFieldDirectionSwitchValueChanged, true);
            app.ZFieldDirectionSwitch.Value = 'Positive';
            app.PowerOffButton = uibutton(app.UIFigure, 'push');
            app.PowerOffButton.ButtonPushedFcn = createCallbackFcn(app, @PowerOffButtonPushed, true);
            app.PowerOffButton.Text = 'Power Off';
            app.UpdateSimulationButton = uibutton(app.UIFigure, 'push');
            app.UpdateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSimulationButtonPushed, true);
            app.UpdateSimulationButton.Text = 'Update Simulation';
            app.SendSignaltoSystemButton = uibutton(app.UIFigure, 'push');
            app.SendSignaltoSystemButton.ButtonPushedFcn = createCallbackFcn(app, @SendSignaltoSystemButtonPushed, true);
            app.SendSignaltoSystemButton.Text = 'Send Signal to System';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Use the switches on the left to adjust the magnetic field density vector in the positive/negative directions. Use the sliders in the middle to adjust the current being output by each respective motor driver. The LEDs on the right indicate whether or not a magnetic field is being powered.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Pressing this button will send a signal to the motor drivers and initiate the magnetic fields.';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Pressing this button will show a simulation of the expected magnetic field density that the system will produce.';
            app.PressthisbuttontocutpowertoallmotordriversLabel = uilabel(app.UIFigure);
            app.PressthisbuttontocutpowertoallmotordriversLabel.Text = 'Press this button to cut power to all motor drivers.';
            app.YFieldDirectionSwitchLabel = uilabel(app.UIFigure);
            app.YFieldDirectionSwitchLabel.Text = 'Y-Field Direction';
            app.YFieldDirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.YFieldDirectionSwitch.Items = {'Negative', 'Positive'};
            app.YFieldDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @YFieldDirectionSwitchValueChanged2, true);
            app.YFieldDirectionSwitch.Value = 'Positive';
            app.XLamp = uilamp(app.UIFigure);
            app.YLamp = uilamp(app.UIFigure);
            app.ZLamp = uilamp(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'This GUI was developed for the Cai Group at UCSD by Winter 2021 MAE 156B Team 5 to control Magnetic Field Density within a reconfigurable 3D Magnetic Control System by varying the electrical currents provided to three sets of electromagnets. The current input to each electromagnet will be controlled using an Arduino Mega 2560 and three Cytron 10A motor driver shields. ';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '_______________________________________________________________________________________________';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '_______________________________________________________________________________________________';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '_______________________________________________________________________________________________';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '_______________________________________________________________________________________________';
            app.XAxesSeparationmmDropDownLabel = uilabel(app.UIFigure);
            app.XAxesSeparationmmDropDownLabel.Text = 'X-Axes Separation [mm]';
            app.XAxesSeparationmmDropDown = uidropdown(app.UIFigure);
            app.XAxesSeparationmmDropDown.Items = {'124', '161', '201', '239', '277', '312'};
            app.XAxesSeparationmmDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxesSeparationmmDropDownValueChanged, true);
            app.XAxesSeparationmmDropDown.Value = '124';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'Adjust the electromagnet separation distance along each axis using the drop down menus to the right to get an accurate simulation.';
            app.YAxesSeparationmmDropDownLabel = uilabel(app.UIFigure);
            app.YAxesSeparationmmDropDownLabel.Text = 'Y-Axes Separation [mm]';
            app.YAxesSeparationmmDropDown = uidropdown(app.UIFigure);
            app.YAxesSeparationmmDropDown.Items = {'124', '161', '201', '239', '277', '312'};
            app.YAxesSeparationmmDropDown.Value = '124';
            app.MagneticFieldDensityVectorMagnitudeLabel = uilabel(app.UIFigure);
            app.MagneticFieldDensityVectorMagnitudeLabel.Text = 'Magnetic Field Density Vector Magnitude:';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulated Magnetic Field Density')
            xlabel(app.UIAxes, 'X-Axes Magnetic Field Density [Milliteslas]')
            ylabel(app.UIAxes, 'Y-Axes Magnetic Field Density [Milliteslas]')
            zlabel(app.UIAxes, 'Z-Axes Magnetic Field Density [Milliteslas]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagneticControlSystemGUI3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end