classdef MagneticControlSystemGUI7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SerialPortSetupTab              matlab.ui.container.Tab
        AvailableSerialPortsListBox     matlab.ui.control.ListBox
        AvailableSerialPortsListBoxLabel  matlab.ui.control.Label
        SelectSerialPortButton          matlab.ui.control.Button
        ScanforAvailableSerialPortsButton  matlab.ui.control.Button
        DMagneticControlSystemLabel_2   matlab.ui.control.Label
        Welcomtothe3DMagneticControlSystemMATLABGUISelectLabel  matlab.ui.control.Label
        ConnectedtoLabel                matlab.ui.control.Label
        SerialPortConnectedLampLabel    matlab.ui.control.Label
        SerialPortConnectedLamp         matlab.ui.control.Lamp
        ControlSystemTab                matlab.ui.container.Tab
        XSlider                         matlab.ui.control.Slider
        XFieldLabel                     matlab.ui.control.Label
        YSlider                         matlab.ui.control.Slider
        YFieldLabel                     matlab.ui.control.Label
        ZSlider                         matlab.ui.control.Slider
        ZFieldLabel                     matlab.ui.control.Label
        DMagneticControlSystemLabel     matlab.ui.control.Label
        UpdateSimulationButton          matlab.ui.control.Button
        SendSignaltoSystemButton        matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        PressthisbuttontocutpowertoallmotordriversLabel  matlab.ui.control.Label
        XLamp                           matlab.ui.control.Lamp
        YLamp                           matlab.ui.control.Lamp
        ZLamp                           matlab.ui.control.Lamp
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        MagneticFieldDensityVectorMagnitudeLabel  matlab.ui.control.Label
        XFieldDirectionSwitchLabel      matlab.ui.control.Label
        XFieldDirectionSwitch           matlab.ui.control.Switch
        ZFieldDirectionSwitchLabel      matlab.ui.control.Label
        ZFieldDirectionSwitch           matlab.ui.control.Switch
        YFieldDirectionSwitchLabel      matlab.ui.control.Label
        YFieldDirectionSwitch           matlab.ui.control.Switch
        XAxesSeparationmmDropDownLabel  matlab.ui.control.Label
        XAxesSeparationmmDropDown       matlab.ui.control.DropDown
        YAxesSeparationmmDropDownLabel  matlab.ui.control.Label
        YAxesSeparationmmDropDown       matlab.ui.control.DropDown
        PowerOffStateButton             matlab.ui.control.StateButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a % Arduino 
        %serialPort = 'com8' %for mac %'/dev/cu.usbmodem145101'
        Baudrate=11000

    end
    
    methods (Access = private)
        
        function plotme(app)
            axis(app.UIAxes,[-20,20,-20,20,-90,90])
            XField=app.XSlider.Value; 
            switch app.XFieldDirectionSwitch.Value
                case 'Positive'
                    XField=XField;
                case 'Negative'
                    XField=XField*-1;
            end
            YField=app.YSlider.Value;
            switch app.YFieldDirectionSwitch.Value
                case 'Positive'
                    YField=YField;
                case 'Negative'
                    YField=YField*-1;
            end
            ZField=app.ZSlider.Value;
            switch app.ZFieldDirectionSwitch.Value
                case 'Positive'
                    ZField=ZField;
                case 'Negative'
                    ZField=ZField*-1;
            end
            Start=[0,0,0];
            % For final app, perform magnetic field density calculation
            % here. Include an option in the GUI to vary the solenoid
            % distance
            
            mu0=4*pi*10^-7;% permeability of air %need to confirm this value
            R=0.037; %Radius of solenoids
            nTurns=110; %Number of coil turns
            separationDist = 0.1328; %Separation Distance between each pair of solenoids
            z_axial = 0; % to compute the magnetic field density at the test chamber
            separationDistX=app.XAxesSeparationmmDropDown.Value;
            switch app.XAxesSeparationmmDropDown.Value
                case '109'
                    BX=0.0017*XField;
                case '160'
                    BX=0.0003*XField;
                case '201'
                    separationDistX=201;
                    BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
                case '239'
                    separationDistX=239;
                    BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
                case '277'
                    separationDistX=277;
                    BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
                case '312'
                    separationDistX=312;
                    BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
            end
            separationDistX=separationDistX/1000;
            separationDistY=app.YAxesSeparationmmDropDown.Value;
            switch app.YAxesSeparationmmDropDown.Value
                case '109'
                    BY=0.0017*YField;
                case '160'
                    BY=0.0003*YField;
                case '201'
                    separationDistY=201;
                    BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
                case '239'
                    separationDistY=239;
                    BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
                case '277'
                    separationDistY=277;
                    BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
                case '312'
                    separationDistY=312;
                    BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
            end
            separationDistY=separationDistY/1000;
            separationDistZ=0.070866; %top bottom solenoid separation distance in mm; 2.79 inches
            %Computes field in Teslas
            BZ=0.0093*ZField;
%             BX=0.5*mu0*nTurns*XField*(R^2)*((R^2+(z_axial+(separationDistX/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistX/2).^2).^(-3/2));
%             BY=0.5*mu0*nTurns*YField*(R^2)*((R^2+(z_axial+(separationDistY/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistY/2).^2).^(-3/2));
%             BZ=0.5*mu0*nTurns*ZField*(R^2)*((R^2+(z_axial+(separationDistZ/2)).^2).^(-3/2)+(R^2+(z_axial-separationDistZ/2).^2).^(-3/2));

            %Converts magnetic field density to milliteslas
            BX=BX*1000;
            BY=BY*1000;
            BZ=BZ*1000;
            pts=[Start;BX,BY,BZ];

            app.XFieldLabel.Text = char("X-Axis Magnetic Field Density: " + string(BX) + ' Milliteslas at '+string(XField)+' Amps');
            app.YFieldLabel.Text = char("Y-Axis Magnetic Field Density: " + string(BY) + ' Milliteslas at '+string(YField)+' Amps');
            app.ZFieldLabel.Text = char("Z-Axis Magnetic Field Density: " + string(BZ) + ' Milliteslas at '+string(ZField)+' Amps');
            cla(app.UIAxes)
            plot3(app.UIAxes,0,0,0,'o','linewidth',4,'Color','k');
            hold(app.UIAxes,'on')
            plot3(app.UIAxes,pts(:,1),pts(:,2),pts(:,3),'Color','r','linewidth',4)
            view(app.UIAxes,[-5 -5 2]) 
            grid(app.UIAxes, 'on')
            legend(app.UIAxes,'Test Chamber','Simulated Magnetic Field Vector','location',"South")
            
            xlabel(app.UIAxes,'X-Axis Magnetic Field Density [mT]'); 
            ylabel(app.UIAxes,'Y-Axis Magnetic Field Density [mT]');
            zlabel(app.UIAxes,'Z-Axis Magnetic Field Density [mT]');
            title(app.UIAxes,'Magnetic Field Density Vector')
            Magnitude=sqrt(BX^2+BY^2+BZ^2);
            app.MagneticFieldDensityVectorMagnitudeLabel.Text = char("Magnetic Field Density Vector Magnitude: " + string(Magnitude) + ' Milliteslas');
        end
        
        function sliderset(app)
            app.XFieldLabel.Text = char("Current [Amps] Supplied to X-Axis Electromagnets");
            app.YFieldLabel.Text = char("Current [Amps] Supplied to Y-Axis Electromagnets");
            app.ZFieldLabel.Text = char("Current [Amps] Supplied to Z-Axis Electromagnets");
        end
        
        
        function ammeter2currentx(app)
            V=readVoltage(app.a,'A9'); %reads the current of the ammeter
            xcurrent = abs(9.912*V - 24.9313);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialPortConnectedLamp.Enable='off'
            s=serialportlist;
            app.AvailableSerialPortsListBox.Items=s;
            %if the system is selecting the wrong port; comment out this if
            %statement and its contents
            if exist("s")&&(~isempty(s))
                clear app.a
                app.a = arduino(app.AvailableSerialPortsListBox.Value, 'Mega2560','baudrate',11000);
                disp(app.AvailableSerialPortsListBox.Value)
                app.SerialPortConnectedLamp.Enable='on'
                app.ConnectedtoLabel.Text=char('Connected to: ' + string(app.AvailableSerialPortsListBox.Value))
            end

            app.plotme();
            app.sliderset();
            app.XLamp.Enable = 'off';
            app.YLamp.Enable = 'off';
            app.ZLamp.Enable = 'off';
            
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValueX = event.Value;
            %XField=event.Value;
            app.XFieldLabel.Text = char("X Direction Applied Current: " + string(changingValueX) + ' Amps');
            %writePWMDutyCycle(app.a, 'D4', changingValueX/10.0);
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValueY = event.Value;
            app.YFieldLabel.Text = char("Y Direction Magnetic Field Density: " + string(changingValueY) + ' Amps');
            %writePWMDutyCycle(app.a, 'D5', changingValueY/10.0);            
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            changingValueZ = event.Value;
            app.ZFieldLabel.Text = char("Z Direction Magnetic Field Density: " + string(changingValueZ) + ' Amps');
            %writePWMDutyCycle(app.a, 'D6', changingValueZ/10.0); 
        end

        % Value changed function: ZFieldDirectionSwitch
        function ZFieldDirectionSwitchValueChanged(app, event)
            value = app.ZFieldDirectionSwitch.Value;
%             switch value
%                 case 'Positive'
%                     writePWMDutyCycle(app.a, 'D7', 1);
%                 case 'Negative'
%                     writePWMDutyCycle(app.a, 'D7', 0);
%             end
        end

        % Callback function
        function YFieldDirectionSwitchValueChanged(app, event)
            
        end

        % Value changed function: XFieldDirectionSwitch
        function XFieldDirectionSwitchValueChanged(app, event)
            value = app.XFieldDirectionSwitch.Value;
%             switch value
%                 case 'Positive'
%                     %writePWMDutyCycle(app.a, 'D2', 1);
%                 case 'Negative'
%                     %writePWMDutyCycle(app.a, 'D2', 0);
%             end
        end

        % Button pushed function: SendSignaltoSystemButton
        function SendSignaltoSystemButtonPushed(app, event)
            maxcurrent=9.5;
            % proportional controller
            Kp=.025;
            KpGain=1+Kp;
            KpLoss=1-Kp;
            % X Direction Pin Assignments: dir= 4, pwm=5, amm=10
            
            %turns the lamp on once the current is set; not yet make it
            %change colors
            %writePWMDutyCycle(app.a, 'D5', app.XSlider.Value/10.0);
            if app.XSlider.Value>0
                app.XLamp.Enable = 'on';
            else
                app.XLamp.Enable = 'off';
            end
            
            switch app.XFieldDirectionSwitch.Value
                case 'Positive'
                    writePWMDutyCycle(app.a, 'D4', 1);
                case 'Negative'
                    writePWMDutyCycle(app.a, 'D4', 0);
            end
            
            Xinputvalue=app.XSlider.Value;
            Xammeter=readVoltage(app.a,'A10');
            Xcurrent = abs(9.912*Xammeter - 24.9313);
            
            if exist('Xpreviousvalue','var')==1
                Xpreviouscurrent;
                if (Xpreviouscurrent==Xinputvalue) %won't go here unless it's an exact match; could make a new variable that is defined at the beginning of the loop
                    writePWMDutyCycle(app.a, 'D5',Xvalue/10);
                elseif Xinputvalue <= 0.1
                    writePWMDutyCycle(app.a, 'D5',0);
                    Xpreviousvalue = 1;
                    Xpreviouscurrent = 0;
                elseif (Xpreviouscurrent<=Xinputvalue) %PWM needs to increase
                    Xvalue=Xpreviousvalue;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D5',0);
                        return
                    end
                    while (Xinputvalue<=maxcurrent)&&(Xcurrent<=Xinputvalue) && (~app.PowerOffStateButton.Value)
                        Xammeter=readVoltage(app.a,'A10');
                        for i = 1:10
                            Xammeter =readVoltage(app.a,'A10') + Xammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D5',0);
                            return
                        end
                        Xammeter = Xammeter/11;
                        Xcurrent = abs(9.912*Xammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Xcurrent>=maxcurrent)||(Xcurrent>=Xinputvalue)
                            Xpreviousvalue=Xvalue;
                            Xpreviouscurrent = Xcurrent;
                            break
                        else
                            Xvalue=Xvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D5',Xvalue/10);
                            pause(0.1)
                        end
                    end
                elseif (Xpreviouscurrent>Xinputvalue) %PWM needs to decrease
                    Xvalue=Xpreviousvalue;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D5',0);
                        return
                    end
                    while (Xcurrent<maxcurrent)&&(Xcurrent>Xinputvalue) && (~app.PowerOffStateButton.Value)
                        Xammeter=readVoltage(app.a,'A10');
                        for i = 1:10
                            Xammeter = readVoltage(app.a,'A10') + Xammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D5',0);
                            return
                        end
                        Xammeter = Xammeter/11;
                        Xcurrent = abs(9.912*Xammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Xcurrent>maxcurrent)||(Xcurrent<=Xinputvalue)
                            Xpreviousvalue=Xvalue;
                            Xpreviouscurrent=Xcurrent;
                            break
                        else
                            Xvalue=Xvalue*KpLoss;
                            writePWMDutyCycle(app.a, 'D5',Xvalue/10);
                            pause(0.1)
                        end
                    end
                    %     elseif (Xinputvalue>maxcurrent) %this will only be needed if
                    %     slider limites are altered
                    %         Xinputvalue=5;
                    %writePWMvalue(max value) % need to compute max value
                end
                
            elseif exist('Xpreviousvalue','var')==0
                if Xinputvalue>1
                    Xvalue=1;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D5',0);
                        return
                    end
                    while (Xinputvalue<=maxcurrent)&&(Xcurrent<=Xinputvalue) && (~app.PowerOffStateButton.Value)
                        Xammeter=readVoltage(app.a,'A10');
                        for i = 1:10
                            Xammeter = readVoltage(app.a,'A10') + Xammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D5',0);
                            return
                        end
                        Xammeter = Xammeter/11;
                        Xcurrent = abs(9.912*Xammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Xcurrent>=maxcurrent)||(Xcurrent>=Xinputvalue)
                            Xpreviousvalue=Xvalue;
                            Xpreviouscurrent = Xcurrent;
                            break
                        else
                            Xvalue=Xvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D5',Xvalue/10);
                            pause(0.1)
                        end
                    end
                elseif Xinputvalue <= 0.1
                    writePWMDutyCycle(app.a, 'D5',0);
                    Xpreviousvalue = 1;
                    Xpreviouscurrent = 0;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D5',0);
                        return
                    end
                elseif Xinputvalue<=1
                    Xvalue=0.1;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D5',0);
                        return
                    end
                    while (Xcurrent<=Xinputvalue) && (~app.PowerOffStateButton.Value)&& (~app.PowerOffStateButton.Value)
                        Xammeter=readVoltage(app.a,'A10');
                        for i = 1:10
                            Xammeter = readVoltage(app.a,'A10') + Xammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D5',0);
                            return
                        end
                        Xammeter = Xammeter/11;
                        Xcurrent = abs(9.912*Xammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Xcurrent>=Xinputvalue)||(Xcurrent>=Xinputvalue)
                            Xpreviousvalue=Xvalue;
                            Xpreviouscurrent = Xcurrent;
                            break
                        else
                            Xvalue=Xvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D5',Xvalue/10);
                            pause(0.1)
                        end
                    end
                end
            end
            if app.XSlider.Value>0
                app.XLamp.Color = [0,1,0];
            else
                app.XLamp.Enable = 'off';
            end


            %% Y Direction Pin Assignments: dir=7, pwm=6, amm=8
            %writePWMDutyCycle(app.a, 'D6', app.YSlider.Value/10.0);
            if app.YSlider.Value>0
                app.YLamp.Enable = 'on';
            else
                app.YLamp.Enable = 'off';
            end
            switch app.YFieldDirectionSwitch.Value
                case 'Positive'
                    writePWMDutyCycle(app.a, 'D7', 1);
                case 'Negative'
                    writePWMDutyCycle(app.a, 'D7', 0);
            end
            
            Yinputvalue=app.YSlider.Value;
            Yammeter=readVoltage(app.a,'A8');
            Ycurrent = abs(9.912*Yammeter - 24.9313);
            if exist('Ypreviousvalue','var')==1
                Ypreviouscurrent;
                if (Ypreviouscurrent==Yinputvalue) %won't go here unless it's an exact match; could make a new variable that is defined at the beginning of the loop
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D6',0);
                        return
                    end
                    writePWMDutyCycle(app.a, 'D6',Yvalue/10);
                elseif Yinputvalue <= 0.1
                    writePWMDutyCycle(app.a, 'D6',0);
                    Ypreviousvalue = 1;
                    Ypreviouscurrent = 0;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D6',0);
                        return
                    end
                elseif (Ypreviouscurrent<=Yinputvalue) %PWM needs to increase
                    Yvalue=Ypreviousvalue;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D6',0);
                        return
                    end
                    while (Yinputvalue<=maxcurrent)&&(Ycurrent<=Yinputvalue)&& (~app.PowerOffStateButton.Value)
                        Yammeter=readVoltage(app.a,'A8');
                        for i = 1:10
                            Yammeter =readVoltage(app.a,'A8') + Yammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D6',0);
                            return
                        end
                        Yammeter = Yammeter/11;
                        Ycurrent = abs(9.912*Yammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Ycurrent>=maxcurrent)||(Ycurrent>=Yinputvalue)
                            Ypreviousvalue=Yvalue;
                            Ypreviouscurrent = Ycurrent;
                            break
                        else
                            Yvalue=Yvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D6',Yvalue/10);
                            pause(0.1)
                        end
                    end
                elseif (Ypreviouscurrent>Yinputvalue) %PWM needs to decrease
                    Yvalue=Ypreviousvalue;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D6',0);
                        return
                    end
                    while (Ycurrent<maxcurrent)&&(Ycurrent>Yinputvalue)&& (~app.PowerOffStateButton.Value)
                        Yammeter=readVoltage(app.a,'A8');
                        for i = 1:10
                            Yammeter = readVoltage(app.a,'A8') + Yammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D6',0);
                            return
                        end
                        Yammeter = Yammeter/11;
                        Ycurrent = abs(9.912*Yammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Ycurrent>maxcurrent)||(Ycurrent<=Yinputvalue)
                            Ypreviousvalue=Yvalue;
                            Ypreviouscurrent=Ycurrent;
                            break
                        else
                            Yvalue=Yvalue*KpLoss;
                            writePWMDutyCycle(app.a, 'D6',Yvalue/10);
                            pause(0.1)
                        end
                    end
                    %     elseif (Xinputvalue>maxcurrent) %this will only be needed if
                    %     slider limites are altered
                    %         Xinputvalue=5;
                    %writePWMvalue(max value) % need to compute max value
                end
                
            elseif exist('Ypreviousvalue','var')==0
                if app.PowerOffStateButton.Value==1
                    writePWMDutyCycle(app.a, 'D6',0);
                    return
                end
                if Yinputvalue>1
                    Yvalue=1;
                    while (Yinputvalue<=maxcurrent)&&(Ycurrent<=Yinputvalue)&& (~app.PowerOffStateButton.Value)
                        Yammeter=readVoltage(app.a,'A8');
                        for i = 1:10
                            Yammeter = readVoltage(app.a,'A8') + Yammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D6',0);
                            return
                        end
                        Yammeter = Yammeter/11;
                        Ycurrent = abs(9.912*Yammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Ycurrent>=maxcurrent)||(Ycurrent>=Yinputvalue)
                            Ypreviousvalue=Yvalue;
                            Ypreviouscurrent = Ycurrent;
                            break
                        else
                            Yvalue=Yvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D6',Yvalue/10);
                            pause(0.1)
                        end
                    end
                elseif Yinputvalue <= 0.1
                    writePWMDutyCycle(app.a, 'D6',0);
                    Ypreviousvalue = 1;
                    Ypreviouscurrent = 0;
                elseif Yinputvalue<=1
                    Yvalue=0.1;
                    while (Ycurrent<=Yinputvalue)&& (~app.PowerOffStateButton.Value)
                        Yammeter=readVoltage(app.a,'A8');
                        for i = 1:10
                            Yammeter = readVoltage(app.a,'A8') + Yammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D6',0);
                            return
                        end
                        Yammeter = Yammeter/11;
                        Ycurrent = abs(9.912*Yammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Ycurrent>=Yinputvalue)||(Ycurrent>=Yinputvalue)
                            Ypreviousvalue=Yvalue;
                            Ypreviouscurrent = Ycurrent;
                            break
                        else
                            Yvalue=Yvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D6',Yvalue/10);
                            pause(0.1)
                        end
                    end
                end
            end
            if app.YSlider.Value>0
                app.YLamp.Color = [0,1,0];
            else
                app.YLamp.Enable = 'off';
            end


            % Z Direction Pin Assignments: dir=2 pwm=3 amm=9
            % writePWMDutyCycle(app.a, 'D3', app.ZSlider.Value/10.0);
            if app.ZSlider.Value>0
                app.ZLamp.Enable = 'on';
            else
                app.ZLamp.Enable = 'off';
            end
            switch app.ZFieldDirectionSwitch.Value
                case 'Positive'
                    writePWMDutyCycle(app.a, 'D2', 1);
                case 'Negative'
                    writePWMDutyCycle(app.a, 'D2', 0);
            end


            Zinputvalue=app.ZSlider.Value;
            Zammeter=readVoltage(app.a,'A9');
            Zcurrent = abs(9.912*Zammeter - 24.9313);
            if exist('Zpreviousvalue','var')==1
                Zpreviouscurrent;
                if (Zpreviouscurrent==Zinputvalue) %won't go here unless it's an exact match; could make a new variable that is defined at the beginning of the loop
                    writePWMDutyCycle(app.a, 'D3',Zvalue/10);
                elseif Zinputvalue <= 0.1
                    writePWMDutyCycle(app.a, 'D3',0);
                    Zpreviousvalue = 1;
                    Zpreviouscurrent = 0;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D3',0);
                        return
                    end
                elseif (Zpreviouscurrent<=Zinputvalue) %PWM needs to increase
                    Zvalue=Zpreviousvalue;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D3',0);
                        return
                    end
                    while (Zinputvalue<=maxcurrent)&&(Zcurrent<=Zinputvalue)&& (~app.PowerOffStateButton.Value)
                        Zammeter=readVoltage(app.a,'A9');
                        for i = 1:10
                            Zammeter =readVoltage(app.a,'A9') + Zammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D3',0);
                            return
                        end
                        Zammeter = Zammeter/11;
                        Zcurrent = abs(9.912*Zammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Zcurrent>=maxcurrent)||(Zcurrent>=Zinputvalue)
                            Zpreviousvalue=Zvalue;
                            Zpreviouscurrent = Zcurrent;
                            break
                        else
                            Zvalue=Zvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D3',Zvalue/10);
                            pause(0.1)
                        end
                    end
                elseif (Zpreviouscurrent>Zinputvalue) %PWM needs to decrease
                    Zvalue=Zpreviousvalue;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D3',0);
                        return
                    end
                    while (Zcurrent<maxcurrent)&&(Zcurrent>Zinputvalue) && (~app.PowerOffStateButton.Value)
                        Zammeter=readVoltage(app.a,'A9');
                        for i = 1:10
                            Zammeter = readVoltage(app.a,'A9') + Zammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D3',0);
                            return
                        end
                        Zammeter = Zammeter/11;
                        Zcurrent = abs(9.912*Zammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Zcurrent>maxcurrent)||(Zcurrent<=Zinputvalue)
                            Zpreviousvalue=Zvalue;
                            Zpreviouscurrent=Zcurrent;
                            break
                        else
                            Zvalue=Zvalue*KpLoss;
                            writePWMDutyCycle(app.a, 'D3',Zvalue/10);
                            pause(0.1)
                        end
                    end
                    %     elseif (Xinputvalue>maxcurrent) %this will only be needed if
                    %     slider limites are altered
                    %         Xinputvalue=5;
                    %writePWMvalue(max value) % need to compute max value
                end
                
            elseif exist('Zpreviousvalue','var')==0
                if Zinputvalue>1
                    Zvalue=1;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D3',0);
                        return
                    end
                    while (Zinputvalue<=maxcurrent)&&(Zcurrent<=Zinputvalue)&& (~app.PowerOffStateButton.Value)
                        Zammeter=readVoltage(app.a,'A9');
                        for i = 1:10
                            Zammeter = readVoltage(app.a,'A9') + Zammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D3',0);
                            return
                        end
                        Zammeter = Zammeter/11;
                        Zcurrent = abs(9.912*Zammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Zcurrent>=maxcurrent)||(Zcurrent>=Zinputvalue)
                            Zpreviousvalue=Zvalue;
                            Zpreviouscurrent = Zcurrent;
                            break
                        else
                            Zvalue=Zvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D3',Zvalue/10);
                            pause(0.1)
                        end
                    end
                elseif Zinputvalue <= 0.1
                    writePWMDutyCycle(app.a, 'D3',0);
                    Zpreviousvalue = 1;
                    Zpreviouscurrent = 0;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D3',0);
                        return
                    end
                elseif Zinputvalue<=1
                    Zvalue=0.1;
                    if app.PowerOffStateButton.Value==1
                        writePWMDutyCycle(app.a, 'D3',0);
                        return
                    end
                    while (Zcurrent<=Zinputvalue) && (~app.PowerOffStateButton.Value)
                        Zammeter=readVoltage(app.a,'A9');
                        for i = 1:10
                            Zammeter = readVoltage(app.a,'A9') + Zammeter;
                            pause(.001)
                        end
                        if app.PowerOffStateButton.Value==1
                            writePWMDutyCycle(app.a, 'D3',0);
                            return
                        end
                        Zammeter = Zammeter/11;
                        Zcurrent = abs(9.912*Zammeter - 24.9313);
                        %current = ammeter2current(ammeter);
                        if (Zcurrent>=Zinputvalue)||(Zcurrent>=Zinputvalue)
                            Zpreviousvalue=Zvalue;
                            Zpreviouscurrent = Zcurrent;
                            break
                        else
                            Zvalue=Zvalue*KpGain;
                            writePWMDutyCycle(app.a, 'D3',Zvalue/10);
                            pause(0.1)
                        end
                    end
                end
            end
            if app.ZSlider.Value>0
                app.ZLamp.Color = [0,1,0];
            else
                app.ZLamp.Enable = 'off';
            end
            
            if app.PowerOffStateButton.Value
                writePWMDutyCycle(app.a, 'D3',0);
                writePWMDutyCycle(app.a, 'D5',0);
                writePWMDutyCycle(app.a, 'D6',0);
                
            end


            app.plotme()
            
        end

        % Button pushed function: UpdateSimulationButton
        function UpdateSimulationButtonPushed(app, event)
            app.plotme()
        end

        % Callback function
        function PowerOffButtonPushed(app, event)
            writePWMDutyCycle(app.a, 'D3', 0);
            writePWMDutyCycle(app.a, 'D5', 0);
            writePWMDutyCycle(app.a, 'D6', 0);
            app.XLamp.Color=[1,0,0];
            app.YLamp.Color=[1,0,0];
            app.ZLamp.Color=[1,0,0];
            app.XLamp.Enable = 'off';
            app.YLamp.Enable = 'off';
            app.ZLamp.Enable = 'off';
%             try
%                 app.XSlider.Value = str2double(5);
%                 app.YSlider.Value = str2double(0);
%                 app.ZSlider.Value = str2double(0);
%             catch ME
%                 
%             end
        end

        % Value changed function: YFieldDirectionSwitch
        function YFieldDirectionSwitchValueChanged2(app, event)
            value = app.YFieldDirectionSwitch.Value;
            
        end

        % Value changed function: XAxesSeparationmmDropDown
        function XAxesSeparationmmDropDownValueChanged(app, event)
            XSeparationDistancemm = app.XAxesSeparationmmDropDown.Value;
            
        end

        % Value changed function: PowerOffStateButton
        function PowerOffStateButtonValueChanged(app, event)
            value = app.PowerOffStateButton.Value;
%             if value==1
%                 app.PowerOffStateButton.Value=1; %this will tell the device to end the process and power down
%             else
%                 app.PowerOffStateButton.Value=0;
%             end
            
            writePWMDutyCycle(app.a, 'D3', 0);
            writePWMDutyCycle(app.a, 'D5', 0);
            writePWMDutyCycle(app.a, 'D6', 0);
            app.XLamp.Color=[1,0,0];
            app.YLamp.Color=[1,0,0];
            app.ZLamp.Color=[1,0,0];
            app.XLamp.Enable = 'off';
            app.YLamp.Enable = 'off';
            app.ZLamp.Enable = 'off';
        end

        % Button pushed function: ScanforAvailableSerialPortsButton
        function ScanforAvailableSerialPortsButtonPushed(app, event)
            s=serialportlist;
            app.AvailableSerialPortsListBox.Items=s;
            disp(app.AvailableSerialPortsListBox.Value)
        end

        % Button pushed function: SelectSerialPortButton
        function SelectSerialPortButtonPushed(app, event)
%             clear app.a
%             if exist("s")&&(~isempty(s))                 
%                 app.a = arduino(app.AvailableSerialPortsListBox.Value, 'Mega2560','baudrate',11000);
%                 disp(app.AvailableSerialPortsListBox.Value)
%                 app.SerialPortConnectedLamp.Enable='on'
%                 app.ConnectedtoLabel.Text=char('Connected to: ' + string(app.AvailableSerialPortsListBox.Value))
%             end
            clear app.a

            %serialPort = 'com8'
            app.a = arduino(app.AvailableSerialPortsListBox.Value, 'Mega2560');
            disp(app.AvailableSerialPortsListBox.Value)
            app.SerialPortConnectedLamp.Enable='on'
            app.ConnectedtoLabel.Text=char('Connected to: ' + string(app.AvailableSerialPortsListBox.Value))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SerialPortSetupTab = uitab(app.TabGroup);
            app.SerialPortSetupTab.Title = 'Serial Port Setup';
            app.AvailableSerialPortsListBox = uilistbox(app.SerialPortSetupTab);
            app.AvailableSerialPortsListBoxLabel = uilabel(app.SerialPortSetupTab);
            app.AvailableSerialPortsListBoxLabel.Text = 'Available Serial Port(s):';
            app.SelectSerialPortButton = uibutton(app.SerialPortSetupTab, 'push');
            app.SelectSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSerialPortButtonPushed, true);
            app.SelectSerialPortButton.Text = 'Select Serial Port';
            app.ScanforAvailableSerialPortsButton = uibutton(app.SerialPortSetupTab, 'push');
            app.ScanforAvailableSerialPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ScanforAvailableSerialPortsButtonPushed, true);
            app.ScanforAvailableSerialPortsButton.Text = 'Scan for Available Serial Ports';
            app.DMagneticControlSystemLabel_2 = uilabel(app.SerialPortSetupTab);
            app.DMagneticControlSystemLabel_2.Text = '3D Magnetic Control System ';
            app.Welcomtothe3DMagneticControlSystemMATLABGUISelectLabel = uilabel(app.SerialPortSetupTab);
            app.Welcomtothe3DMagneticControlSystemMATLABGUISelectLabel.Text = 'Welcome to the 3D Magnetic Control System MATLAB GUI. Select the port that you hooked your Arduino Mega 2560 up to from the list below. If you attached your Arduino after starting the app, press the "Scan for Available Serial Ports" button to update the list. Once you have selected the port that you are using, press the "Select Serial Port" button to make that your set serial port. Once you are done setting up your serial port, you can navigate to the "Control System" tab above. If your desired port is selected when you start the system, no action is required.';
            app.ConnectedtoLabel = uilabel(app.SerialPortSetupTab);
            app.ConnectedtoLabel.Text = 'Connected to: ';
            app.SerialPortConnectedLampLabel = uilabel(app.SerialPortSetupTab);
            app.SerialPortConnectedLampLabel.Text = 'Serial Port Connected';
            app.SerialPortConnectedLamp = uilamp(app.SerialPortSetupTab);
            app.ControlSystemTab = uitab(app.TabGroup);
            app.ControlSystemTab.Title = 'Control System';
            app.XSlider = uislider(app.ControlSystemTab);
            app.XSlider.Limits = [0 9.5];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XFieldLabel = uilabel(app.ControlSystemTab);
            app.XFieldLabel.Text = 'X-Field';
            app.YSlider = uislider(app.ControlSystemTab);
            app.YSlider.Limits = [0 9.5];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YFieldLabel = uilabel(app.ControlSystemTab);
            app.YFieldLabel.Text = 'Y-Field';
            app.ZSlider = uislider(app.ControlSystemTab);
            app.ZSlider.Limits = [0 9.5];
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZFieldLabel = uilabel(app.ControlSystemTab);
            app.ZFieldLabel.Text = 'Z-Field';
            app.DMagneticControlSystemLabel = uilabel(app.ControlSystemTab);
            app.DMagneticControlSystemLabel.Text = '3D Magnetic Control System ';
            app.UpdateSimulationButton = uibutton(app.ControlSystemTab, 'push');
            app.UpdateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSimulationButtonPushed, true);
            app.UpdateSimulationButton.Text = 'Update Simulation';
            app.SendSignaltoSystemButton = uibutton(app.ControlSystemTab, 'push');
            app.SendSignaltoSystemButton.ButtonPushedFcn = createCallbackFcn(app, @SendSignaltoSystemButtonPushed, true);
            app.SendSignaltoSystemButton.Text = 'Send Signal to System';
            app.Label_2 = uilabel(app.ControlSystemTab);
            app.Label_2.Text = 'Use the switches on the left to adjust the magnetic field density vector in the positive/negative directions. Use the sliders in the middle to adjust the current being output by each respective motor driver. The LEDs on the right indicate whether or not a magnetic field is being powered. When the LED is red and powered on, power is being supplied. When the LED is green and powered on, the desired current is being supplied.';
            app.Label_3 = uilabel(app.ControlSystemTab);
            app.Label_3.Text = 'Pressing this button will send a signal to the motor drivers and initiate the magnetic fields.';
            app.Label_4 = uilabel(app.ControlSystemTab);
            app.Label_4.Text = 'Pressing this button will show a simulation of the expected magnetic field density that the system will produce.';
            app.PressthisbuttontocutpowertoallmotordriversLabel = uilabel(app.ControlSystemTab);
            app.PressthisbuttontocutpowertoallmotordriversLabel.Text = 'Press this button to cut power to all motor drivers. When the button is pressed, the color will change to a dull red and the device will not be able to turn back on. To turn the device back on, press the button again so that it turn to a bright red color again.';
            app.XLamp = uilamp(app.ControlSystemTab);
            app.YLamp = uilamp(app.ControlSystemTab);
            app.ZLamp = uilamp(app.ControlSystemTab);
            app.Label_5 = uilabel(app.ControlSystemTab);
            app.Label_5.Text = 'This GUI was developed for the Cai Group at UCSD by Winter 2021 MAE 156B Team 5 to control Magnetic Field Density within a reconfigurable 3D Magnetic Control System by varying the electrical currents provided to three sets of electromagnets. The current input to each electromagnet will be controlled using an Arduino Mega 2560 and three Cytron 10A motor driver shields. ';
            app.Label_6 = uilabel(app.ControlSystemTab);
            app.Label_6.Text = '_______________________________________________________________________________________________';
            app.Label_7 = uilabel(app.ControlSystemTab);
            app.Label_7.Text = '_______________________________________________________________________________________________';
            app.Label_8 = uilabel(app.ControlSystemTab);
            app.Label_8.Text = '_______________________________________________________________________________________________';
            app.Label_9 = uilabel(app.ControlSystemTab);
            app.Label_9.Text = '_______________________________________________________________________________________________';
            app.Label_10 = uilabel(app.ControlSystemTab);
            app.Label_10.Text = 'Adjust the electromagnet separation distance along each axis using the drop down menus to the right to get an accurate simulation.';
            app.MagneticFieldDensityVectorMagnitudeLabel = uilabel(app.ControlSystemTab);
            app.MagneticFieldDensityVectorMagnitudeLabel.Text = 'Magnetic Field Density Vector Magnitude:';
            app.XFieldDirectionSwitchLabel = uilabel(app.ControlSystemTab);
            app.XFieldDirectionSwitchLabel.Text = 'X-Field Direction';
            app.XFieldDirectionSwitch = uiswitch(app.ControlSystemTab, 'slider');
            app.XFieldDirectionSwitch.Items = {'Negative', 'Positive'};
            app.XFieldDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @XFieldDirectionSwitchValueChanged, true);
            app.XFieldDirectionSwitch.Value = 'Positive';
            app.ZFieldDirectionSwitchLabel = uilabel(app.ControlSystemTab);
            app.ZFieldDirectionSwitchLabel.Text = 'Z-Field Direction';
            app.ZFieldDirectionSwitch = uiswitch(app.ControlSystemTab, 'slider');
            app.ZFieldDirectionSwitch.Items = {'Negative', 'Positive'};
            app.ZFieldDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @ZFieldDirectionSwitchValueChanged, true);
            app.ZFieldDirectionSwitch.Value = 'Positive';
            app.YFieldDirectionSwitchLabel = uilabel(app.ControlSystemTab);
            app.YFieldDirectionSwitchLabel.Text = 'Y-Field Direction';
            app.YFieldDirectionSwitch = uiswitch(app.ControlSystemTab, 'slider');
            app.YFieldDirectionSwitch.Items = {'Negative', 'Positive'};
            app.YFieldDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @YFieldDirectionSwitchValueChanged2, true);
            app.YFieldDirectionSwitch.Value = 'Positive';
            app.XAxesSeparationmmDropDownLabel = uilabel(app.ControlSystemTab);
            app.XAxesSeparationmmDropDownLabel.Text = 'X-Axes Separation [mm]';
            app.XAxesSeparationmmDropDown = uidropdown(app.ControlSystemTab);
            app.XAxesSeparationmmDropDown.Items = {'109', '160', '201', '239', '277', '312'};
            app.XAxesSeparationmmDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxesSeparationmmDropDownValueChanged, true);
            app.XAxesSeparationmmDropDown.Value = '109';
            app.YAxesSeparationmmDropDownLabel = uilabel(app.ControlSystemTab);
            app.YAxesSeparationmmDropDownLabel.Text = 'Y-Axes Separation [mm]';
            app.YAxesSeparationmmDropDown = uidropdown(app.ControlSystemTab);
            app.YAxesSeparationmmDropDown.Items = {'109', '160', '201', '239', '277', '312'};
            app.YAxesSeparationmmDropDown.Value = '109';
            app.PowerOffStateButton = uibutton(app.ControlSystemTab, 'state');
            app.PowerOffStateButton.ValueChangedFcn = createCallbackFcn(app, @PowerOffStateButtonValueChanged, true);
            app.PowerOffStateButton.Text = 'Power Off State';
            app.UIAxes = uiaxes(app.ControlSystemTab);
            title(app.UIAxes, 'Simulated Magnetic Field Density')
            xlabel(app.UIAxes, 'X-Axes Magnetic Field Density [Milliteslas]')
            ylabel(app.UIAxes, 'Y-Axes Magnetic Field Density [Milliteslas]')
            zlabel(app.UIAxes, 'Z-Axes Magnetic Field Density [Milliteslas]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MagneticControlSystemGUI7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end